classdef uno < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Image          matlab.ui.control.Image
        YourHandPanel  matlab.ui.container.Panel
    end

    
    properties (Access = private)
        numPlayers;
        playerTurn;
        topCard;
        playersNumCards;

        myPlayerNum;
        timestamp;
        handImages;

        channelID = 2364789;
        writeKey = 'I2UG9UCL0S6X3SCM';
        readKey = 'MJ8791RFGBHXVTG1';

    end
    
    properties (Access = public)
        deck;
        player;% Description
    end
    
    methods (Access = private)
        function [] = updateturn(app)
            %When calling this function provide both the current turn number from app
            %as well as the totalplayer number also from app
            if app.playerTurn < app.numPlayers
              app.playerTurn = app.playerTurn + 1;
            else
                app.playerTurn = 1;
            end
        end
        
        function writeData(app, timestamp)
            %This function requires an input timestamp in date-time format.
            %the timestamp is the last time thingspeak was written to and
            %can be obtained from the function thingSpeakRead

            loop = true;
            while loop
                if (datetime("now") - timestamp) > duration(0,0,20)
                    thingSpeakWrite(app.channelID,[app.numPlayers, app.playerTurn,...
                        app.topCard,app.playersNumCards(1),app.playersNumCards(2),...
                        app.playersNumCards(3),app.playersNumCards(4)],...
                        "WriteKey",app.writeKey,"Timestamp",datetime("now"));
                    loop = false;
                else
                    pause(2);
                end
            end
        end
        
        function readData(app)
            %simply reads the data for thingspeak and updates the data in
            %the app accordingly

            %call thing speak
            [data,app.timestamp] = thingSpeakRead(app.channelID,"ReadKey",app.readKey);
            %assign data to correct properties
            app.numPlayers = data(1);
            app.playerTurn = data(2);
            app.topCard = data(3);
            app.playersNumCards = [data(4), data(5), data(6), data(7)];
        end
        
        function displayHand(app)
            %displays the hand to the user
            pathToMLAPP = fileparts(mfilename('fullpath'));
            width = app.YourHandPanel.Position(3);
            numCards = length(app.player.playerHand);
            spacing = (width -20)/(numCards);
            if spacing > 65
                spacing = 65;
            end

            pos = linspace(5,(width-spacing-20),numCards)
            
            
            for i = 1:numCards
                images(i) = uiimage(app.YourHandPanel,"Position",[pos(i),25,spacing,spacing*1.4]);
                char(app.player.playerHand(i).imageSource); 
                card = app.player.playerHand(i);
                file = char(string(pathToMLAPP) + string(card.imageSource));
                images(i).ImageSource = file;
            end
            app.handImages = images
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
            %initialize instances of the deck and players class
            app.deck = Deck();
            app.player = Player(app.deck);

            %Pulls top card from that deck and assigns it to the startcard
            %as well as the topcard value
            startcard = app.deck.pullCard;
            app.Image.ImageSource = startcard.imageSource;
            app.topCard = startcard;

            %show the user hand on the GUI
            app.displayHand()
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.YourHandPanel = uipanel(app.UIFigure);
            app.YourHandPanel.Title = 'Your Hand';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UnoCards', 'cardback.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uno

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end